classdef Application_4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SpectrumanalysisTab             matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        FFTlengthNEditField             matlab.ui.control.NumericEditField
        FFTlengthNEditFieldLabel        matlab.ui.control.Label
        UpdatePlotsButton               matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        SamplingratefsEditFieldLabel    matlab.ui.control.Label
        SamplingratefsEditField         matlab.ui.control.NumericEditField
        DFTresolutionEditFieldLabel     matlab.ui.control.Label
        DFTresolutionEditField          matlab.ui.control.NumericEditField
        NoiseSNREditFieldLabel          matlab.ui.control.Label
        NoiseSNREditField               matlab.ui.control.NumericEditField
        WindowingandSpectralleakageTab  matlab.ui.container.Tab
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes3_2                       matlab.ui.control.UIAxes
        FFTLengthEditFieldLabel         matlab.ui.control.Label
        FFTLengthEditField              matlab.ui.control.NumericEditField
        UpdatePlotsButton_2             matlab.ui.control.Button
        SamplingrateEditFieldLabel      matlab.ui.control.Label
        SamplingrateEditField           matlab.ui.control.NumericEditField
        UIAxes3_3                       matlab.ui.control.UIAxes
        UIAxes3_4                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        L = 400  ;% % Number of points in signal 
        f1 = 40  ;% Description
        f2 = 42 ;
        f3 = 80 ;
        A1 = 2.2 ; % Description
        A2 = 2.4 ;
        A3 = 1.2 ;
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UpdatePlotsButton
        function UpdatePlotsButtonPushed(app, event)
            %% Initialization
            fs = app.SamplingratefsEditField.Value ; %% Sampling Rate in Hz
            N  = app.FFTlengthNEditField.Value; %% FFT length
            
            
            t = 0:1/fs: (app.L)/fs ;  % time axis (s)
            
            %% Signal Generation 
            s = app.A1*cos(2*pi*app.f1*t) + app.A2*cos(2*pi*app.f2*t) + app.A3*sin(2*pi*app.f3*t) ;
            
            %% Spectrum formation - clean signal
            
            S = fft(s,N) ; 
            Smag = abs(S) ; 
            
            power = log10(Smag.^2/N); %% power spectrum
            
            f = (0:N-1)*(fs/N); %% frequency vector
            
            
            %% Additive noise
            snr = app.NoiseSNREditField.Value ; %% SNR in dB
            sn = awgn(s,snr) ;  %% add white gaussian noise 
          
            
            %% Spectrum formation - noisy signal
            
            Sn = fft(sn,N) ;
            Smagn = abs(Sn) ; 
            
            power_n = log10(Smagn.^2/N); %% power spectrum


           %% Detection based Constante False Alarm Ratio


            %% Plots
            plot(app.UIAxes2,t,sn, 'r' )
            
            plot(app.UIAxes2_2,t,s, 'b'  )
          
            plot(app.UIAxes,f(1:floor(N/2)),power_n(1:floor(N/2)), 'r')
           
            plot(app.UIAxes_2,f(1:floor(N/2)),power(1:floor(N/2)), 'b')
            
            %% DFT resolution
            r = fs/N ; %% in Hz
            app.DFTresolutionEditField.Value = r;


        end

        % Button pushed function: UpdatePlotsButton_2
        function UpdatePlotsButton_2Pushed(app, event)
            fs = app.SamplingrateEditField.Value ; %% Sampling Rate in Hz
            N  = app.FFTLengthEditField.Value; %% FFT length

            t = 0:1/fs: (app.L)/fs ;  % time axis (s)
           
            
            %% Original signal %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% Signal Generation 
            s = app.A1*cos(2*pi*app.f1*t) + app.A2*cos(2*pi*app.f2*t) + app.A3*sin(2*pi*app.f3*t) ;
            %  s = awgn(s,5) ;
            %% Spectrum
            S = fft(s,N) ; 
            Smag = abs(S) ; 
            
            power = log10(Smag.^2/N); %% power spectrum
            
            f = (0:N-1)*(fs/N); %% frequency vector
           
            %% Windowed signal %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% windowed signal generation
            %% Hanning
            Win_Length = length(s) ;
            w  = window(@hann,Win_Length);
            sw = w.*s';
           
   
            %% Spectrum
            Sw = fft(sw,N) ; 
            Smagw = abs(Sw) ; 
            
            powerw = log10(Smagw.^2/N); %% power spectrum
 
            %% Blackman
           
            wBK  = window(@blackman,Win_Length);
            swBK = wBK.*s';
           
   
            %% Spectrum
            SwBK = fft(swBK,N) ; 
            SmagwBK = abs(SwBK) ; 
            
            powerwBK = log10(SmagwBK.^2/N); %% power spectrum
            %% Plots
                
            %% original signal
            plot(app.UIAxes3,t,s, 'r' )

            plot(app.UIAxes3_2,f(1:floor(N/2)),power(1:floor(N/2)), 'r')


           %% windowed signal
            app.UIAxes3_3.cla; 
            hold(app.UIAxes3_3,'on')
            
            plot(app.UIAxes3_3,t,sw, 'k' )
            
            plot(app.UIAxes3_3,t,swBK, 'm' )
           
            legend(app.UIAxes3_3,{'Hanning Window','Blackman Window'}); 
            
            hold(app.UIAxes3_3,'off')
            
            app.UIAxes3_4.cla; 
            
            hold(app.UIAxes3_4,'on')
            
            plot(app.UIAxes3_4,f(1:floor(N/2)),powerw(1:floor(N/2)), 'K')
            
            plot(app.UIAxes3_4,f(1:floor(N/2)),powerwBK(1:floor(N/2)), 'm')
            
            hold(app.UIAxes3_4,'off')
            
            legend(app.UIAxes3_4,{'Hanning Window','Blackman Window'}); 


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SpectrumanalysisTab = uitab(app.TabGroup);
            app.SpectrumanalysisTab.Title = 'Spectrum analysis';
            app.UIAxes = uiaxes(app.SpectrumanalysisTab);
            title(app.UIAxes, 'Spectrum of noisy signal')
            xlabel(app.UIAxes, 'Frequency (Hz)')
            ylabel(app.UIAxes, 'Magnitude(dB)')
            app.FFTlengthNEditField = uieditfield(app.SpectrumanalysisTab, 'numeric');
            app.FFTlengthNEditFieldLabel = uilabel(app.SpectrumanalysisTab);
            app.FFTlengthNEditFieldLabel.Text = 'FFT length (N)';
            app.UpdatePlotsButton = uibutton(app.SpectrumanalysisTab, 'push');
            app.UpdatePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotsButtonPushed, true);
            app.UpdatePlotsButton.Text = 'Update Plots';
            app.UIAxes2 = uiaxes(app.SpectrumanalysisTab);
            title(app.UIAxes2, 'Noisy Signal')
            xlabel(app.UIAxes2, 'Time(s)')
            ylabel(app.UIAxes2, 'Amplitude (V)')
            app.UIAxes_2 = uiaxes(app.SpectrumanalysisTab);
            title(app.UIAxes_2, 'Spectrum')
            xlabel(app.UIAxes_2, 'Frequency (Hz)')
            ylabel(app.UIAxes_2, 'Magnitude(dB)')
            app.UIAxes2_2 = uiaxes(app.SpectrumanalysisTab);
            title(app.UIAxes2_2, 'Signal')
            xlabel(app.UIAxes2_2, 'Time(s)')
            ylabel(app.UIAxes2_2, 'Amplitude (V)')
            app.SamplingratefsEditFieldLabel = uilabel(app.SpectrumanalysisTab);
            app.SamplingratefsEditFieldLabel.Text = 'Sampling rate (fs)';
            app.SamplingratefsEditField = uieditfield(app.SpectrumanalysisTab, 'numeric');
            app.DFTresolutionEditFieldLabel = uilabel(app.SpectrumanalysisTab);
            app.DFTresolutionEditFieldLabel.Text = 'DFT resolution';
            app.DFTresolutionEditField = uieditfield(app.SpectrumanalysisTab, 'numeric');
            app.NoiseSNREditFieldLabel = uilabel(app.SpectrumanalysisTab);
            app.NoiseSNREditFieldLabel.Text = 'Noise (SNR)';
            app.NoiseSNREditField = uieditfield(app.SpectrumanalysisTab, 'numeric');
            app.WindowingandSpectralleakageTab = uitab(app.TabGroup);
            app.WindowingandSpectralleakageTab.Title = 'Windowing and Spectral leakage';
            app.UIAxes3 = uiaxes(app.WindowingandSpectralleakageTab);
            title(app.UIAxes3, 'Signal')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes3_2 = uiaxes(app.WindowingandSpectralleakageTab);
            title(app.UIAxes3_2, 'Spectrum')
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            app.FFTLengthEditFieldLabel = uilabel(app.WindowingandSpectralleakageTab);
            app.FFTLengthEditFieldLabel.Text = 'FFT Length';
            app.FFTLengthEditField = uieditfield(app.WindowingandSpectralleakageTab, 'numeric');
            app.UpdatePlotsButton_2 = uibutton(app.WindowingandSpectralleakageTab, 'push');
            app.UpdatePlotsButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotsButton_2Pushed, true);
            app.UpdatePlotsButton_2.Text = 'Update Plots';
            app.SamplingrateEditFieldLabel = uilabel(app.WindowingandSpectralleakageTab);
            app.SamplingrateEditFieldLabel.Text = 'Sampling rate';
            app.SamplingrateEditField = uieditfield(app.WindowingandSpectralleakageTab, 'numeric');
            app.UIAxes3_3 = uiaxes(app.WindowingandSpectralleakageTab);
            title(app.UIAxes3_3, 'Windowed Signal')
            xlabel(app.UIAxes3_3, 'X')
            ylabel(app.UIAxes3_3, 'Y')
            app.UIAxes3_4 = uiaxes(app.WindowingandSpectralleakageTab);
            title(app.UIAxes3_4, 'Spectrum of windowed signal')
            xlabel(app.UIAxes3_4, 'X')
            ylabel(app.UIAxes3_4, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Application_4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end